classdef state_visualization_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RunsimulationButton  matlab.ui.control.Button
        UIAxes_XYZ           matlab.ui.control.UIAxes
        UIAxes_U4            matlab.ui.control.UIAxes
        UIAxes_U3            matlab.ui.control.UIAxes
        UIAxes_U2            matlab.ui.control.UIAxes
        UIAxes_U1            matlab.ui.control.UIAxes
        UIAxes_r             matlab.ui.control.UIAxes
        UIAxes_q             matlab.ui.control.UIAxes
        UIAxes_p             matlab.ui.control.UIAxes
        UIAxes_psi           matlab.ui.control.UIAxes
        UIAxes_theta         matlab.ui.control.UIAxes
        UIAxes_phi           matlab.ui.control.UIAxes
        UIAxes_v_z           matlab.ui.control.UIAxes
        UIAxes_v_y           matlab.ui.control.UIAxes
        UIAxes_v_x           matlab.ui.control.UIAxes
        UIAxes_z             matlab.ui.control.UIAxes
        UIAxes_y             matlab.ui.control.UIAxes
        UIAxes_x             matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LoadDataButtonPushed(app, event)


        end

        % Callback function
        function InitializeParamsButtonPushed(app, event)

        end

        % Button pushed function: RunsimulationButton
        function RunSimulationButtonPushed(app, event)
            try
                % Name of the Simulink model
                modelName = 'quadrotor_dynamics_model'; % Replace with your Simulink file name without ".slx"
                % Load and run the Simulink model
                load_system(modelName);
                simOut = sim(modelName);
                
                % Display success message
                uialert(app.UIFigure, 'Simulation completed successfully.', 'Success');

                cla(app.UIAxes_x);
                cla(app.UIAxes_y);
                cla(app.UIAxes_z);
                cla(app.UIAxes_v_x);
                cla(app.UIAxes_v_y);
                cla(app.UIAxes_v_z);
                cla(app.UIAxes_phi);
                cla(app.UIAxes_theta);
                cla(app.UIAxes_psi);
                cla(app.UIAxes_p);
                cla(app.UIAxes_q);
                cla(app.UIAxes_r);
                cla(app.UIAxes_U1);
                cla(app.UIAxes_U2);
                cla(app.UIAxes_U3);
                cla(app.UIAxes_U4);
                
                % (Optional) Plot results in UIAxes
                % Extract and plot data (example for 'x' signal)
                time = simOut.x.Time;
                xData = simOut.x.Data;
                yData = simOut.y.Data;
                zData = simOut.z.Data;
                v_xData = simOut.x_dot.Data;
                v_yData = simOut.y_dot.Data;
                v_zData = simOut.z_dot.Data;
                phiData = simOut.phi.Data;
                thetaData = simOut.theta.Data;
                psiData = simOut.psi.Data;
                pData = simOut.p.Data;
                qData = simOut.q.Data;
                rData = simOut.r.Data;

                x_cData = simOut.x_c.Data;
                y_cData = simOut.y_c.Data;
                z_cData = simOut.z_c.Data;
                v_x_cData = simOut.v_x_c.Data;
                v_y_cData = simOut.v_y_c.Data;
                v_z_cData = simOut.v_z_c.Data;
                phi_cData = simOut.phi_c.Data;
                theta_cData = simOut.theta_c.Data;
                psi_cData = simOut.psi_c.Data;
                p_cData = simOut.p_c.Data;
                q_cData = simOut.q_c.Data;
                r_cData = simOut.r_c.Data;

                U1Data = simOut.U1.Data;
                U2Data = simOut.U2.Data;
                U3Data = simOut.U3.Data;
                U4Data = simOut.U4.Data;

                hold(app.UIAxes_x, 'on');
                plot(app.UIAxes_x, time, xData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_x, time, x_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_x, 'on');
                box(app.UIAxes_x, 'on');
                hold(app.UIAxes_x, 'off');

                hold(app.UIAxes_y, 'on');
                plot(app.UIAxes_y, time, yData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_y, time, y_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_y, 'on');
                box(app.UIAxes_y, 'on');
                hold(app.UIAxes_y, 'off');

                hold(app.UIAxes_z, 'on');
                plot(app.UIAxes_z, time, zData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_z, time, z_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_z, 'on');
                box(app.UIAxes_z, 'on');
                hold(app.UIAxes_z, 'off');

                hold(app.UIAxes_v_x, 'on');
                plot(app.UIAxes_v_x, time, v_xData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_v_x, time, v_x_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_v_x, 'on');
                box(app.UIAxes_v_x, 'on');
                hold(app.UIAxes_v_x, 'off');

                hold(app.UIAxes_v_y, 'on');
                plot(app.UIAxes_v_y, time, v_yData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_v_y, time, v_y_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_v_y, 'on');
                box(app.UIAxes_v_y, 'on');
                hold(app.UIAxes_v_y, 'off');

                hold(app.UIAxes_v_z, 'on');
                plot(app.UIAxes_v_z, time, v_zData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_v_z, time, v_z_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_v_z, 'on');
                box(app.UIAxes_v_z, 'on');
                hold(app.UIAxes_v_z, 'off');

                hold(app.UIAxes_phi, 'on');
                plot(app.UIAxes_phi, time, phiData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_phi, time, phi_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_phi, 'on');
                box(app.UIAxes_phi, 'on');
                hold(app.UIAxes_phi, 'off');

                hold(app.UIAxes_theta, 'on');
                plot(app.UIAxes_theta, time, thetaData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_theta, time, theta_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_theta, 'on');
                box(app.UIAxes_theta, 'on');
                hold(app.UIAxes_theta, 'off');

                hold(app.UIAxes_psi, 'on');
                plot(app.UIAxes_psi, time, psiData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_psi, time, psi_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_psi, 'on');
                box(app.UIAxes_psi, 'on');
                hold(app.UIAxes_psi, 'off');

                hold(app.UIAxes_p, 'on');
                plot(app.UIAxes_p, time, pData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_p, time, p_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_p, 'on');
                box(app.UIAxes_p, 'on');
                hold(app.UIAxes_p, 'off');

                hold(app.UIAxes_q, 'on');
                plot(app.UIAxes_q, time, qData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_q, time, q_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_q, 'on');
                box(app.UIAxes_q, 'on');
                hold(app.UIAxes_q, 'off');

                hold(app.UIAxes_r, 'on');
                plot(app.UIAxes_r, time, rData, 'r', 'LineWidth', 1.5);
                plot(app.UIAxes_r, time, r_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_r, 'on');
                box(app.UIAxes_r, 'on');
                hold(app.UIAxes_r, 'off');

                plot(app.UIAxes_U1, time, U1Data, 'r', 'LineWidth', 1.5);
                grid(app.UIAxes_U1, 'on');
                box(app.UIAxes_U1, 'on');

                plot(app.UIAxes_U2, time, U2Data, 'r', 'LineWidth', 1.5);
                grid(app.UIAxes_U2, 'on');
                box(app.UIAxes_U2, 'on');

                plot(app.UIAxes_U3, time, U3Data, 'r', 'LineWidth', 1.5);
                grid(app.UIAxes_U3, 'on');
                box(app.UIAxes_U3, 'on');

                plot(app.UIAxes_U4, time, U4Data, 'r', 'LineWidth', 1.5);
                grid(app.UIAxes_U4, 'on');
                box(app.UIAxes_U4, 'on');

                hold(app.UIAxes_XYZ, 'on');
                plot3(app.UIAxes_XYZ, xData, yData, zData, 'r', 'LineWidth', 1.5);
                plot3(app.UIAxes_XYZ, x_cData, y_cData, z_cData, 'b', 'LineWidth', 1.5);
                grid(app.UIAxes_XYZ, 'on');
                box(app.UIAxes_XYZ, 'on');
                hold(app.UIAxes_XYZ, 'off');
          
            catch ME
                % Display error if simulation fails
                uialert(app.UIFigure, ['Error running simulation: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'x v/s t')
            xlabel(app.UIAxes_x, 't')
            ylabel(app.UIAxes_x, 'x')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_y = uiaxes(app.UIFigure);
            title(app.UIAxes_y, 'y v/s t')
            xlabel(app.UIAxes_y, 't')
            ylabel(app.UIAxes_y, 'y')
            zlabel(app.UIAxes_y, 'Z')
            app.UIAxes_z = uiaxes(app.UIFigure);
            title(app.UIAxes_z, 'z v/s t')
            xlabel(app.UIAxes_z, 't')
            ylabel(app.UIAxes_z, 'z')
            zlabel(app.UIAxes_z, 'Z')
            app.UIAxes_v_x = uiaxes(app.UIFigure);
            title(app.UIAxes_v_x, 'v_x v/s t')
            xlabel(app.UIAxes_v_x, 't')
            ylabel(app.UIAxes_v_x, 'v_x')
            zlabel(app.UIAxes_v_x, 'Z')
            app.UIAxes_v_y = uiaxes(app.UIFigure);
            title(app.UIAxes_v_y, 'v_y v/s t')
            xlabel(app.UIAxes_v_y, 't')
            ylabel(app.UIAxes_v_y, 'v_y')
            zlabel(app.UIAxes_v_y, 'Z')
            app.UIAxes_v_z = uiaxes(app.UIFigure);
            title(app.UIAxes_v_z, 'v_z v/s t')
            xlabel(app.UIAxes_v_z, 't')
            ylabel(app.UIAxes_v_z, 'v_z')
            zlabel(app.UIAxes_v_z, 'Z')
            app.UIAxes_phi = uiaxes(app.UIFigure);
            title(app.UIAxes_phi, '\phi v/s t')
            xlabel(app.UIAxes_phi, 't')
            ylabel(app.UIAxes_phi, '\phi')
            zlabel(app.UIAxes_phi, 'Z')
            app.UIAxes_theta = uiaxes(app.UIFigure);
            title(app.UIAxes_theta, '\theta v/s t')
            xlabel(app.UIAxes_theta, 't')
            ylabel(app.UIAxes_theta, '\theta')
            zlabel(app.UIAxes_theta, 'Z')
            app.UIAxes_psi = uiaxes(app.UIFigure);
            title(app.UIAxes_psi, '\psi v/s t')
            xlabel(app.UIAxes_psi, 't')
            ylabel(app.UIAxes_psi, '\psi')
            zlabel(app.UIAxes_psi, 'Z')
            app.UIAxes_p = uiaxes(app.UIFigure);
            title(app.UIAxes_p, 'p v/s t')
            xlabel(app.UIAxes_p, 't')
            ylabel(app.UIAxes_p, 'p')
            zlabel(app.UIAxes_p, 'Z')
            app.UIAxes_q = uiaxes(app.UIFigure);
            title(app.UIAxes_q, 'q v/s t')
            xlabel(app.UIAxes_q, 't')
            ylabel(app.UIAxes_q, 'q')
            zlabel(app.UIAxes_q, 'Z')
            app.UIAxes_r = uiaxes(app.UIFigure);
            title(app.UIAxes_r, 'r v/s t')
            xlabel(app.UIAxes_r, 't')
            ylabel(app.UIAxes_r, 'r')
            zlabel(app.UIAxes_r, 'Z')
            app.UIAxes_U1 = uiaxes(app.UIFigure);
            title(app.UIAxes_U1, 'U1 v/s t')
            xlabel(app.UIAxes_U1, 't')
            ylabel(app.UIAxes_U1, 'U1')
            zlabel(app.UIAxes_U1, 'Z')
            app.UIAxes_U2 = uiaxes(app.UIFigure);
            title(app.UIAxes_U2, 'U2 v/s t')
            xlabel(app.UIAxes_U2, 't')
            ylabel(app.UIAxes_U2, 'U2')
            zlabel(app.UIAxes_U2, 'Z')
            app.UIAxes_U3 = uiaxes(app.UIFigure);
            title(app.UIAxes_U3, 'U3 v/s t')
            xlabel(app.UIAxes_U3, 't')
            ylabel(app.UIAxes_U3, 'U3')
            zlabel(app.UIAxes_U3, 'Z')
            app.UIAxes_U4 = uiaxes(app.UIFigure);
            title(app.UIAxes_U4, 'U4 v/s t')
            xlabel(app.UIAxes_U4, 't')
            ylabel(app.UIAxes_U4, 'U4')
            zlabel(app.UIAxes_U4, 'Z')
            app.UIAxes_XYZ = uiaxes(app.UIFigure);
            title(app.UIAxes_XYZ, 'XYZ plot')
            xlabel(app.UIAxes_XYZ, 'x')
            ylabel(app.UIAxes_XYZ, 'y')
            zlabel(app.UIAxes_XYZ, 'z')
            app.RunsimulationButton = uibutton(app.UIFigure, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = state_visualization_app

            % Associate the Simulink Model
            app.Simulation = simulation('linearized_drone_dynmaics');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end